classdef ADCSgui_v2_dark < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ADCSfigure                      matlab.ui.Figure
        GridLayout25                    matlab.ui.container.GridLayout
        TabGroup3                       matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        GridLayout26                    matlab.ui.container.GridLayout
        Panel_3                         matlab.ui.container.Panel
        GridLayout35                    matlab.ui.container.GridLayout
        Ver2Release3Label               matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ControllerGainsPanel            matlab.ui.container.Panel
        GridLayout31                    matlab.ui.container.GridLayout
        K_wEditField                    matlab.ui.control.NumericEditField
        K_wEditFieldLabel               matlab.ui.control.Label
        BdotControllerLabel             matlab.ui.control.Label
        K_pEditField                    matlab.ui.control.NumericEditField
        K_pEditFieldLabel               matlab.ui.control.Label
        K_dEditField                    matlab.ui.control.NumericEditField
        K_dEditFieldLabel               matlab.ui.control.Label
        ModifiedPDControllerLabel       matlab.ui.control.Label
        SimulationSetupandRunPanel      matlab.ui.container.Panel
        GridLayout33                    matlab.ui.container.GridLayout
        SimulationTimesecEditField      matlab.ui.control.NumericEditField
        SimulationTimesecEditFieldLabel  matlab.ui.control.Label
        TruestatesCheckBox              matlab.ui.control.CheckBox
        EnablefilteringCheckBox         matlab.ui.control.CheckBox
        EnableexternaltorquesCheckBox   matlab.ui.control.CheckBox
        RunSimulationButton             matlab.ui.control.Button
        LoadSimulinkModelButton         matlab.ui.control.Button
        MassPropertiesPanel             matlab.ui.container.Panel
        GridLayout32                    matlab.ui.container.GridLayout
        I_izmathrmkgm2Label             matlab.ui.control.Label
        I_iymathrmkgm2Label             matlab.ui.control.Label
        I_ixmathrmkgm2Label             matlab.ui.control.Label
        I_zxEditField                   matlab.ui.control.NumericEditField
        I_zyEditField                   matlab.ui.control.NumericEditField
        I_yzEditField                   matlab.ui.control.NumericEditField
        I_yxEditField                   matlab.ui.control.NumericEditField
        I_xzEditField                   matlab.ui.control.NumericEditField
        I_xyEditField                   matlab.ui.control.NumericEditField
        I_yyEditField                   matlab.ui.control.NumericEditField
        I_xxEditField                   matlab.ui.control.NumericEditField
        I_zzEditField                   matlab.ui.control.NumericEditField
        J_wheels                        matlab.ui.control.NumericEditField
        I_zmathrmkgm2Label              matlab.ui.control.Label
        WheelsMomentofInertiaLabel      matlab.ui.control.Label
        I_zimathrmkgm2Label             matlab.ui.control.Label
        I_yimathrmkgm2Label             matlab.ui.control.Label
        I_ximathrmkgm2Label             matlab.ui.control.Label
        SpacecraftInertiaMatrixLabel    matlab.ui.control.Label
        OrbitElementsPanel              matlab.ui.container.Panel
        GridLayout30                    matlab.ui.container.GridLayout
        t_psecEditField                 matlab.ui.control.NumericEditField
        t_psecLabel                     matlab.ui.control.Label
        omegadegEditField               matlab.ui.control.NumericEditField
        omegadegEditFieldLabel          matlab.ui.control.Label
        OmegadegEditField               matlab.ui.control.NumericEditField
        OmegadegEditFieldLabel          matlab.ui.control.Label
        idegEditField                   matlab.ui.control.NumericEditField
        idegLabel                       matlab.ui.control.Label
        EccentricityEditField           matlab.ui.control.NumericEditField
        EccentricityLabel               matlab.ui.control.Label
        AltitudekmEditField             matlab.ui.control.NumericEditField
        AltitudekmEditFieldLabel        matlab.ui.control.Label
        InitialConditionsPanel          matlab.ui.container.Panel
        GridLayout29                    matlab.ui.container.GridLayout
        q_0_4Label                      matlab.ui.control.Label
        q_0_3Label                      matlab.ui.control.Label
        q_0_2Label                      matlab.ui.control.Label
        wW_1                            matlab.ui.control.NumericEditField
        mathbfomega_W_0radsLabel        matlab.ui.control.Label
        wW_3                            matlab.ui.control.NumericEditField
        wW_2                            matlab.ui.control.NumericEditField
        ReactionWheelsLabel             matlab.ui.control.Label
        q4_ini                          matlab.ui.control.NumericEditField
        q3_ini                          matlab.ui.control.NumericEditField
        q2_ini                          matlab.ui.control.NumericEditField
        q1_ini                          matlab.ui.control.NumericEditField
        mathbfq_0_1Label                matlab.ui.control.Label
        wB_3                            matlab.ui.control.NumericEditField
        wB_2                            matlab.ui.control.NumericEditField
        wB_1                            matlab.ui.control.NumericEditField
        mathbfomega_b_0mathrmdegsLabel  matlab.ui.control.Label
        SpacecraftBodyLabel             matlab.ui.control.Label
        ModeSelectionPanel              matlab.ui.container.Panel
        GridLayout28                    matlab.ui.container.GridLayout
        ListBox                         matlab.ui.control.ListBox
        AnimationTab                    matlab.ui.container.Tab
        GridLayout24                    matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        ScrubberSlider                  matlab.ui.control.Slider
        Panel_2                         matlab.ui.container.Panel
        GridLayout27                    matlab.ui.container.GridLayout
        AnimationControlLabel           matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        PauseButton                     matlab.ui.control.Button
        PlayButton                      matlab.ui.control.Button
        AnimationTimeLabel              matlab.ui.control.Label
        AnimationSpeedSlider            matlab.ui.control.Slider
        MomentumAxes                    matlab.ui.control.UIAxes
        PointingErrAxes                 matlab.ui.control.UIAxes
        MagMomentAxes                   matlab.ui.control.UIAxes
        AttAxes                         matlab.ui.control.UIAxes
        RatesAxes                       matlab.ui.control.UIAxes
        OrbitAxes                       matlab.ui.control.UIAxes
        OutputTab                       matlab.ui.container.Tab
        GridLayout34                    matlab.ui.container.GridLayout
        Panel_4                         matlab.ui.container.Panel
        GridLayout36                    matlab.ui.container.GridLayout
        NimishDhawanLabel               matlab.ui.control.Label
        GenerateOutputButton            matlab.ui.control.Button
        FileNameEditField               matlab.ui.control.EditField
        FileNameEditFieldLabel          matlab.ui.control.Label
        LocationCEditField              matlab.ui.control.EditField
        LocationCEditFieldLabel         matlab.ui.control.Label
        PlotgraphsCheckBox              matlab.ui.control.CheckBox
        GenerateattitudefileCheckBox    matlab.ui.control.CheckBox
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
    

        % Handles for orbit 3D
        hEarth
        hX; hY; hZ; hSun; hTrail
      
        % Parameters
        Rearth double = 6378*0.8;   % km (scaled globe to match your S-fcn)
        ArrowScale double = 1000;   % km
        LastRedrawT double = -inf;  % throttling
    
        % Queue and listener
        dq
        dqListener
    
        % Model control (set to your model name)
        ModelName char = 'ADCS_onOrbit'

        t double = [];                 % time (Nx1)
        att double = [];               % [roll pitch yaw] (Nx3)
        rates double = [];             % [wx wy wz] (Nx3)
        h_w double = [];
        magM double = [];
        err double = [];
    
        k (1,1) double = 1;            % current frame index
        N (1,1) double = 1;            % total frames
        dt (1,1) double = 0.1;         % median sample time
        idxStep (1,1) double = 1;      % frames advanced per tick (derived)
        aniTmr timer;                  % animation timer
        speedFactor double = 100;        % slider value (0.25..4)
        baseFPS     double = 30;       % target draw rate
        rI double = [];                 % [N x 3] ECI position (km)
        % C_BI double = [];               % [3 x 3 x N] DCM I->B
        q double = [];                  % [N x 4] quaternion
        sI double = [];                 % [N x 3] optional Sun vector (ECI)
        bodyAxisLen double = 10;       % axis arrow length (km)
        tPlay0 uint64 = uint64(0); 
        tDisp0 double = 0;     % display time at play start

        % hX matlab.graphics.chart.primitive.Quiver = matlab.graphics.chart.primitive.Quiver.empty;
        % hY matlab.graphics.chart.primitive.Quiver = matlab.graphics.chart.primitive.Quiver.empty;
        % hZ matlab.graphics.chart.primitive.Quiver = matlab.graphics.chart.primitive.Quiver.empty;
        % hSun matlab.graphics.chart.primitive.Quiver = matlab.graphics.chart.primitive.Quiver.empty;
        % hTrail matlab.graphics.chart.primitive.Line = matlab.graphics.chart.primitive.Line.empty;

        hAtt  = gobjects(1,3);
        hRat  = gobjects(1,3);
        hMom  = gobjects(1,3);
        hMag  = gobjects(1,3);
        hErr  = gobjects(1,3);

    end
    
    
    methods (Access = private)
    
        function d = CustomWaitBox(app,Message) 
            fig = app.ADCSfigure; 
            d = uiprogressdlg(fig,'Title',Message, 'Indeterminate','on'); 
            drawnow
        end
        
       function initOrbitViewer(app)
            ax = app.OrbitAxes;  % your UIAxes3D
            cla(ax); hold(ax,'on'); grid(ax,'on'); axis(ax,'equal');
            xlabel(ax,'X (km)'); ylabel(ax,'Y (km)'); zlabel(ax,'Z (km)');
            title(ax,'Orbit Viewer'); view(ax, 40, 25);
      
            % Body axes quivers, anchored at spacecraft position (set in updates)
            app.hX = quiver3(ax,0,0,0,0,0,0,'r','LineWidth',3, ...
                 'AutoScale','off','MaxHeadSize',2, ...
                 'ShowArrowHead','on','Clipping','off');
            app.hY = quiver3(ax,0,0,0,0,0,0,'b','LineWidth',3, ...
                 'AutoScale','off','MaxHeadSize',2, ...
                 'ShowArrowHead','on','Clipping','off');
            app.hZ = quiver3(ax,0,0,0,0,0,0,'g','LineWidth',3, ...
                 'AutoScale','off','MaxHeadSize',2, ...
                 'ShowArrowHead','on','Clipping','off');
        
            % Orbit trail
            app.hTrail = plot3(ax, NaN, NaN, NaN, 'k', 'LineWidth',1.5, ...
                               'HandleVisibility','off');
        
            % Optional Sun vector
            if ~isempty(app.sI)
                app.hSun = quiver3(ax,0,0,0,0,0,0,'Color',[1 0.6 0], ...
                                   'LineWidth',2,'MaxHeadSize',0.5, ...
                                   'HandleVisibility','off');
            end
        
            % Legend using dummy lines so quivers don’t appear as “data1”
            % hxL = plot3(ax,NaN,NaN,NaN,'r-','LineWidth',2);
            % hyL = plot3(ax,NaN,NaN,NaN,'g-','LineWidth',2);
            % hzL = plot3(ax,NaN,NaN,NaN,'b-','LineWidth',2);
            % trL = plot3(ax,NaN,NaN,NaN,'k-','LineWidth',1.5);
            % sunL = plot3(ax,NaN,NaN,NaN,'-','Color',[1 0.6 0],'LineWidth',1.5);
            legend(ax,[app.hX,app.hY,app.hz,app.hTrail,app.hSun],{'+X_b','+Y_b','+Z_b','Orbit Trail','Sun'},'Location','northeast');

        
            % Nice limits based on orbit size
            R = max(vecnorm(app.rI,2,2)); if isempty(R) || ~isfinite(R), R = 7000; end
            m = 1.2*R;
            xlim(ax,[-m m]); ylim(ax,[-m m]); zlim(ax,[-m m]);
        end

        
       function updateOrbitFrame(app, k, a)
        % k = left index (1..N-1), a in [0,1] fraction toward k+1
        % If called with only k, fall back to a = 0 (exact sample)
            if nargin < 3, a = 0; end
        
            % --- position (linear interpolation for smooth path)
            k = max(1, min(size(app.rI,1)-1, k));     % ensure a valid pair
            r  = (1-a)*app.rI(k,:) + a*app.rI(k+1,:);  % 1x3
        
            % --- orientation: SLERP between q(k) and q(k+1)
            q1 = app.q(k,:).';           % 4x1
            q2 = app.q(k+1,:).';         % 4x1
            quat  = app.slerp_q(q1, q2, a);     % 4x1, [E; Eta]
        
            % --- DCM I→B, then transpose to express body axes in ECI
            C_BI = app.quatEEta2dcm(quat);
            C_IB = C_BI.';               % inverse
        
            % Body axes in ECI
            exI = C_IB(:,1).';  eyI = C_IB(:,2).';  ezI = C_IB(:,3).';
        
            % Arrow length scaled to radius (and clamped)
            % L = max(300, min(1500, 0.15*norm(r)));
            L = 2;
        
            % Update quivers
            set(app.hX,'XData',r(1),'YData',r(2),'ZData',r(3), ...
                       'UData',L*exI(1),'VData',L*exI(2),'WData',L*exI(3));
            set(app.hY,'XData',r(1),'YData',r(2),'ZData',r(3), ...
                       'UData',L*eyI(1),'VData',L*eyI(2),'WData',L*eyI(3));
            set(app.hZ,'XData',r(1),'YData',r(2),'ZData',r(3), ...
                       'UData',L*ezI(1),'VData',L*ezI(2),'WData',L*ezI(3));
        
            % Trail (grow to k)
            set(app.hTrail,'XData',app.rI(1:k,1),'YData',app.rI(1:k,2),'ZData',app.rI(1:k,3));
        
            % Optional sun (origin at S/C); scale if unit
            if ~isempty(app.sI)
                s = app.sI(k,:); if norm(s) < 10, s = 3*(s/norm(s)); end
                set(app.hSun,'XData',r(1),'YData',r(2),'ZData',r(3), ...
                             'UData',s(1),'VData',s(2),'WData',s(3));
            end
        end


       function initAnimation(app)
            % --- sizes / timing
            app.N  = size(app.att,1);
            d = diff(app.t); app.dt = median(d(d>0));
            fps = 30;                       % target frame rate
            app.idxStep = max(1, round((1/fps)/app.dt));
            app.k = 1;
            app.AttAxes.XLimMode  = 'auto';
            app.RatesAxes.XLimMode = 'auto';

            % --- Attitude plot
            cla(app.AttAxes); hold(app.AttAxes,'on');
            app.hAtt(:,1) = plot(app.AttAxes, app.t, nan(app.N,1), 'LineWidth',1.2);
            app.hAtt(:,2) = plot(app.AttAxes, app.t, nan(app.N,1), 'LineWidth',1.2);
            app.hAtt(:,3) = plot(app.AttAxes, app.t, nan(app.N,1), 'LineWidth',1.2);
            grid(app.AttAxes,'off'); xlabel(app.AttAxes,'Time (s)');
            ylabel(app.AttAxes,'Euler Angles (deg)'); title(app.AttAxes,'Spacecraft Attitude');
            legend(app.AttAxes, {'Roll','Pitch','Yaw'}, 'Location','northeast');

            % --- Rates plot
            cla(app.RatesAxes); hold(app.RatesAxes,'on');
            app.hRat(:,1) = plot(app.RatesAxes, app.t, nan(app.N,1), 'LineWidth',1.2);
            app.hRat(:,2) = plot(app.RatesAxes, app.t, nan(app.N,1), 'LineWidth',1.2);
            app.hRat(:,3) = plot(app.RatesAxes, app.t, nan(app.N,1), 'LineWidth',1.2);
            grid(app.RatesAxes,'off'); xlabel(app.RatesAxes,'Time (s)');
            ylabel(app.RatesAxes,'\omega_b (deg/s)'); title(app.RatesAxes,'Spacecraft Body Rates');
            legend(app.RatesAxes, {'\omega_x','\omega_y','\omega_z'}, 'Location','northeast');

            % --- Wheels Angular Momentum Plot
            cla(app.MomentumAxes); hold(app.MomentumAxes,'on');
            app.hMom(:,1) = plot(app.MomentumAxes, app.t, nan(app.N,1), 'LineWidth',1.2);
            app.hMom(:,2) = plot(app.MomentumAxes, app.t, nan(app.N,1), 'LineWidth',1.2);
            app.hMom(:,3) = plot(app.MomentumAxes, app.t, nan(app.N,1), 'LineWidth',1.2);
            grid(app.MomentumAxes,'off'); xlabel(app.MomentumAxes,'Time (s)');
            ylabel(app.MomentumAxes,'h (mNms)'); title(app.MomentumAxes,'Stored Angular Momentum');
            legend(app.MomentumAxes, {'Wheel 1','Wheel 2','Wheel 3'}, 'Location','northeast');

            % --- Rods Magnetic Moment 
            cla(app.MagMomentAxes); hold(app.MagMomentAxes,'on');
            app.hMag(:,1) = plot(app.MagMomentAxes, app.t, nan(app.N,1), 'LineWidth',1.2);
            app.hMag(:,2) = plot(app.MagMomentAxes, app.t, nan(app.N,1), 'LineWidth',1.2);
            app.hMag(:,3) = plot(app.MagMomentAxes, app.t, nan(app.N,1), 'LineWidth',1.2);
            grid(app.MagMomentAxes,'off'); xlabel(app.MagMomentAxes,'Time (s)');
            ylabel(app.MagMomentAxes,'m (Am^2)'); title(app.MagMomentAxes,'Magnetic Moment');
            legend(app.MagMomentAxes, {'Rod 1','Rod 2','Rod 3'}, 'Location','northeast');

            % --- Pointing Error Plot
            cla(app.PointingErrAxes); hold(app.PointingErrAxes,'on');
            app.hErr(:,1) = plot(app.PointingErrAxes, app.t, nan(app.N,1), 'Linewidth',1.2);
            app.hErr(:,2) = plot(app.PointingErrAxes, app.t, nan(app.N,1), 'Linewidth',1.2);
            app.hErr(:,3) = plot(app.PointingErrAxes, app.t, nan(app.N,1), 'Linewidth',1.2);
            grid(app.PointingErrAxes,'off'); xlabel(app.PointingErrAxes,'Time (s)');
            ylabel(app.PointingErrAxes,'Euler Angles (deg)');
            ylim(app.PointingErrAxes,[-5 5]);
            title(app.PointingErrAxes,'Pointing Error'); legend(app.PointingErrAxes, {'roll', 'pitch', 'yaw'}, 'Location','northeast');

            % --- Scrubber slider (use seconds directly)
            app.ScrubberSlider.Limits = [app.t(1) app.t(end)];
            app.ScrubberSlider.Value  = app.t(1);
            app.ScrubberSlider.MajorTicks = [app.t(1), app.t(end)/2, app.t(end)];
            
            % --- First render
            app.renderFrame(app.k);

            % --- Timer
            if ~isempty(app.aniTmr) && isvalid(app.aniTmr), stop(app.aniTmr); delete(app.aniTmr); end
            app.aniTmr = timer('ExecutionMode','fixedRate', 'Period', 1/30, ...
                               'TimerFcn', @(~,~) app.stepFrame());

            % Speed slider setup (0.25× to 4×)
            app.AnimationSpeedSlider.Limits      = [100 500];
            app.AnimationSpeedSlider.Value       = 300;
            app.AnimationSpeedSlider.Orientation = 'vertical';
            app.AnimationSpeedSlider.MajorTicks  = [100,500];
            % Place two static UILabels next to the slider in App Designer: “Fast” near top, “Slow” near bottom
        
            % Compute initial step from speed
            app.applySpeed();
       end

       function renderFrame(app, k)
            k = max(1,min(app.N,k));
            tnow = app.t(k);

            % Grow lines up to k
            set(app.hAtt(1), 'YData', app.att(1:k,1), 'XData', app.t(1:k));
            set(app.hAtt(2), 'YData', app.att(1:k,2), 'XData', app.t(1:k));
            set(app.hAtt(3), 'YData', app.att(1:k,3), 'XData', app.t(1:k));

            set(app.hRat(1), 'YData', app.rates(1:k,1), 'XData', app.t(1:k));
            set(app.hRat(2), 'YData', app.rates(1:k,2), 'XData', app.t(1:k));
            set(app.hRat(3), 'YData', app.rates(1:k,3), 'XData', app.t(1:k));

            set(app.hMom(1), 'YData', app.h_w(1:k,1), 'XData', app.t(1:k));
            set(app.hMom(2), 'YData', app.h_w(1:k,2), 'XData', app.t(1:k));
            set(app.hMom(3), 'YData', app.h_w(1:k,3), 'XData', app.t(1:k));

            set(app.hMag(1), 'YData', app.magM(1:k,1), 'XData', app.t(1:k));
            set(app.hMag(2), 'YData', app.magM(1:k,2), 'XData', app.t(1:k));
            set(app.hMag(3), 'YData', app.magM(1:k,3), 'XData', app.t(1:k));

            set(app.hErr(1), 'YData', app.err(1:k,1), 'XData', app.t(1:k));
            set(app.hErr(2), 'YData', app.err(1:k,2), 'XData', app.t(1:k));
            set(app.hErr(3), 'YData', app.err(1:k,3), 'XData', app.t(1:k));

            % Move cursors
            % app.hAttCur.Value = tnow;
            % app.hRatCur.Value = tnow;

            % Keep x-limits nice
            xlim(app.AttAxes,[app.t(1) app.t(end)]);
            xlim(app.RatesAxes,[app.t(1) app.t(end)]);
            xlim(app.MagMomentAxes,[app.t(1) app.t(end)]);
            xlim(app.MomentumAxes,[app.t(1) app.t(end)]);
            xlim(app.PointingErrAxes,[app.t(1) app.t(end)]);

            app.updateOrbitFrame(k);

            drawnow %limitrate nocallbacks
        end

      function stepFrame(app)
            % Desired display time from wall clock and speed
            td = app.tDisp0 + app.speedFactor * toc(app.tPlay0);
            td = min(max(td, app.t(1)), app.t(end));
        
            % Fractional index corresponding to td
            idx = interp1(app.t, 1:numel(app.t), td, 'linear', 'extrap');
            if isnan(idx), return; end
            kL  = max(1, min(floor(idx), app.N-1));  % left index
            a   = idx - kL;                          % 0..1
        
            % Update 2D plots up to kL; move cursors to td
            set(app.hAtt(1), 'YData', app.att(1:kL,1), 'XData', app.t(1:kL));
            set(app.hAtt(2), 'YData', app.att(1:kL,2), 'XData', app.t(1:kL));
            set(app.hAtt(3), 'YData', app.att(1:kL,3), 'XData', app.t(1:kL));

            set(app.hRat(1), 'YData', app.rates(1:kL,1), 'XData', app.t(1:kL));
            set(app.hRat(2), 'YData', app.rates(1:kL,2), 'XData', app.t(1:kL));
            set(app.hRat(3), 'YData', app.rates(1:kL,3), 'XData', app.t(1:kL));

            set(app.hMom(1), 'YData', app.h_w(1:kL,1), 'XData', app.t(1:kL));
            set(app.hMom(2), 'YData', app.h_w(1:kL,2), 'XData', app.t(1:kL));
            set(app.hMom(3), 'YData', app.h_w(1:kL,3), 'XData', app.t(1:kL));

            set(app.hMag(1), 'YData', app.magM(1:kL,1), 'XData', app.t(1:kL));
            set(app.hMag(2), 'YData', app.magM(1:kL,2), 'XData', app.t(1:kL));
            set(app.hMag(3), 'YData', app.magM(1:kL,3), 'XData', app.t(1:kL));

            set(app.hErr(1), 'YData', app.err(1:kL,1), 'XData', app.t(1:kL));
            set(app.hErr(2), 'YData', app.err(1:kL,2), 'XData', app.t(1:kL));
            set(app.hErr(3), 'YData', app.err(1:kL,3), 'XData', app.t(1:kL));
            % app.hAttCur.Value = td;  app.hRatCur.Value = td;
        
            % 3D orbit & body axes with SLERP
            app.updateOrbitFrame(kL, a);
        
            % keep slider synced (seconds)
            app.ScrubberSlider.Value = td;
        
            drawnow limitrate nocallbacks;
        
            % Stop at the end
            if td >= app.t(end)
                stop(app.aniTmr);
            end
        
            % keep track of current integer index (for other UI logic)
            app.k = kL;
      end

        function applySpeed(app)
            % How many samples to advance per timer tick
            % base tick = 1/baseFPS seconds; convert to data frames using dt, then scale by speed
            framesPerTick = (1/app.baseFPS)/app.dt;
            app.idxStep = max(1, round(app.speedFactor * framesPerTick));
        
            % Keep timer period constant for smoothness
            if ~isempty(app.aniTmr) && isvalid(app.aniTmr)
                wasRunning = strcmp(app.aniTmr.Running,'on');
                stop(app.aniTmr);
                app.aniTmr.Period = 1/app.baseFPS;
                if wasRunning, start(app.aniTmr); end
            end

        end
        
        function q = slerp_q(app,q1,q2,a)
            % q1,q2: 4x1, vector-first; a in [0,1]
            q1 = q1/norm(q1); q2 = q2/norm(q2);
            if dot(q1,q2) < 0, q2 = -q2; end      % shortest arc
            c = max(-1,min(1, dot(q1,q2)));
            omega = acos(c);
            if omega < 1e-6
                q = (1-a)*q1 + a*q2;
            else
                s = sin(omega);
                q = (sin((1-a)*omega)/s)*q1 + (sin(a*omega)/s)*q2;
            end
            q = q/norm(q);
        end
    
        function C = quatEEta2dcm(app,q)
            % q: 4x1, vector-first [E; Eta]; returns C_BI (I→B)
            E   = q(1:3); eta = q(4);
            Esk = [ 0     -E(3)  E(2);
                    E(3)   0    -E(1);
                   -E(2)  E(1)   0   ];
            C = (eta^2 - (E.'*E))*eye(3) + 2*(E*E.') - 2*eta*Esk;
        end

        function oe2rv(app)
            h_apogee = app.AltitudekmEditField.Value;
            e = app.EccentricityEditField.Value;
            inc = app.idegEditField.Value;
            raan = app.OmegadegEditField.Value;
            omega = app.omegadegEditField.Value;
            tp = app.t_psecEditField.Value;

            a = h_apogee + 6378.14;
            mu = 398600.4418;  
            n = sqrt(mu/a^3);
            period = 2*pi/n;

            M = n * (0-tp);
            E_pre = M + sin(M)*e;
        
            for k = 1:100
                E_next = E_pre - (E_pre - e*sin(E_pre) - M)/(1 - e*cos(E_pre));
                E_pre = E_next;
            end
            
            eano = E_next;
        
            tano = 2 * atan(tan(eano/2)*sqrt((1+e)/(1-e))); % rad
            % tano = 2 * atan2(sqrt(1+e)*sin(eano/2),sqrt(1-e)*cos(eano/2));
        
            % Position & velocity in Perifocal frame
            p = a * (1 - e^2);
            r_P = (p / (1 + e*cos(tano))) * [cos(tano); sin(tano); 0];
            v_P = sqrt(mu / p) * [-sin(tano); e + cos(tano); 0];
        
            % Rotation Sequence
            C3_omega = [cosd(omega), sind(omega), 0; -sind(omega), cosd(omega), 0; 0, 0, 1];
            C1_i = [1, 0, 0; 0, cosd(inc), sind(inc); 0, -sind(inc), cosd(inc)];
            C3_RAAN = [cosd(raan), sind(raan), 0; -sind(raan), cosd(raan), 0; 0, 0, 1];
        
            C_PI = C3_omega * C1_i * C3_RAAN;
        
            C_IP = C_PI';
           
            % Convert to ECI
            rI = C_IP * r_P;
            vI = C_IP * v_P;

            assignin("base","r_I_ini",rI)
            assignin("base","v_I_ini",vI)
            assignin('base','n',n)
            assignin('base','period',period)

        end

        function makeJ(app)
            I_xx = app.I_xxEditField.Value;
            I_xy = app.I_xyEditField.Value;
            I_xz = app.I_xzEditField.Value;
            I_yx = app.I_yxEditField.Value;
            I_yy = app.I_yyEditField.Value;
            I_yz = app.I_yzEditField.Value;
            I_zx = app.I_zxEditField.Value;
            I_zy = app.I_zyEditField.Value;
            I_zz = app.I_zzEditField.Value;
            
            % Construct inertia matrix
            I_body = [ I_xx  I_xy  I_xz;
                       I_yx  I_yy  I_yz;
                       I_zx  I_zy  I_zz ];

            I_body = (I_body + I_body.') / 2;


            assignin('base','J_body',I_body)

        end

        function setValues(app)
            app.AltitudekmEditFieldValueChanged();
            app.ControllerGainK_dEditFieldValueChanged();
            app.ControllerGainK_pEditFieldValueChanged();
            app.ControllerGainK_wEditFieldValueChanged();
            app.EccentricityEditFieldValueChanged();
            app.EnableexternaltorquesCheckBoxValueChanged();
            app.idegEditFieldValueChanged();
            app.ListBoxValueChanged();
            app.omegadegEditFieldValueChanged();
            app.OmegadegEditFieldValueChanged();
            app.SimulationTimesecEditFieldValueChanged();
            app.t_psecEditFieldValueChanged();
            app.wB_1ValueChanged();
            app.wB_2ValueChanged();
            app.wB_3ValueChanged();
            app.wW_3ValueChanged();
            app.J_wheelsValueChanged();
            app.GenerateattitudefileCheckBoxValueChanged();
            app.PlotgraphsCheckBoxValueChanged();
            app.LocationCEditFieldValueChanged();
            app.FileNameEditFieldValueChanged();
            app.q1_iniValueChanged();
            app.q2_iniValueChanged();
            app.q3_iniValueChanged();
            app.q4_iniValueChanged();
            app.oe2rv();
            app.I_zzEditFieldValueChanged();
            app.I_zxEditFieldValueChanged();
            app.I_zyEditFieldValueChanged();
            app.I_yzEditFieldValueChanged();
            app.I_yyEditFieldValueChanged();
            app.I_yxEditFieldValueChanged();
            app.I_xzEditFieldValueChanged();
            app.I_xyEditFieldValueChanged();
            app.I_xxEditFieldValueChanged();
            app.EnablefilteringCheckBoxValueChanged();
            app.TruestatesCheckBoxValueChanged();
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setValues();
                        
            set(app.RunSimulationButton,'Enable','off')
            set(app.PlayButton,'Enable','off')
            set(app.PauseButton,'Enable','off')
            set(app.StopButton,'Enable','off')
            set(app.ScrubberSlider,'Enable','off')
            set(app.AnimationSpeedSlider,'Enable','off')
            set(app.GenerateOutputButton,'Enable','off')

            %---- ORBIT AXES SETUP ----
            ax = app.OrbitAxes;
            cla(ax); hold(ax,'on'); axis(ax,'equal');
            xlim(ax,[-8e3 8e3]); ylim(ax,[-8e3 8e3]); zlim(ax,[-8e3 8e3]);
            xlabel(ax,'X (km)'); ylabel(ax,'Y (km)'); zlabel(ax,'Z (km)');
            view(ax, 30, 30); grid(ax,'off'); box(ax,'on');
            title(ax,'Orbit Viewer');
        
            % Earth textured sphere
            [x,y,z] = sphere(35);
            try
                S = load('topo.mat','topo'); C = S.topo; 
            catch
                C = ones(size(x));
            end
            app.hEarth = surf(ax, app.Rearth*x, app.Rearth*y, app.Rearth*z, ...
                C, 'FaceColor','texturemap','EdgeColor',[0.4 0.4 0.4], ...
                'LineStyle','-','LineWidth',0.3,'FaceAlpha',1);
        
            % Body axes + trail + sun
            app.hX    = quiver3(ax,0,0,0,0,0,0, app.ArrowScale,'r','LineWidth',3);
            app.hY    = quiver3(ax,0,0,0,0,0,0, app.ArrowScale,'b','LineWidth',3);
            app.hZ    = quiver3(ax,0,0,0,0,0,0, app.ArrowScale,'g','LineWidth',3);
            app.hTrail= plot3(ax, NaN,NaN,NaN, 'k','LineWidth',1.3);
            app.hSun  = quiver3(ax,0,0,0,0,0,0, 1500,'Color',[1 0.6 0],'LineWidth',2);

            hxL = plot3(ax,NaN,NaN,NaN,'r-','LineWidth',2);
            hyL = plot3(ax,NaN,NaN,NaN,'b-','LineWidth',2);
            hzL = plot3(ax,NaN,NaN,NaN,'g-','LineWidth',2);
            trL = plot3(ax,NaN,NaN,NaN,'k-','LineWidth',1.5);
            sunL = plot3(ax,NaN,NaN,NaN,'-','Color',[1 0.6 0],'LineWidth',1.5);
            legend(ax,[hxL,hyL,hzL,trL,sunL],{'+X_b','+Y_b','+Z_b','Orbit Trail','Sun'},'Location','northeast');
            

        end

        % Value changed function: EnableexternaltorquesCheckBox
        function EnableexternaltorquesCheckBoxValueChanged(app, event)
            ext_torque = app.EnableexternaltorquesCheckBox.Value;
            assignin('base','ext_torque',ext_torque)
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            waitbox = app.CustomWaitBox('Running simulation. Please be patient...');
            stopapp = forceStop;
            set_param(app.ModelName,'SimulationMode','Normal');
            app.setValues();
            
            % Extracting Data
            simOut = sim(app.ModelName);
            assignin('base','simOut',simOut)
            tout = simOut.tout;
            assignin('base','tout',tout)
            logs = simOut.logsout;
            assignin('base','logs',logs)

            roll    = squeeze(simOut.roll);
            pitch   = squeeze(simOut.pitch);
            yaw     = squeeze(simOut.yaw);

            w1_body = squeeze(simOut.w1_body)*57.2958;
            w2_body = squeeze(simOut.w2_body)*57.2958;
            w3_body = squeeze(simOut.w3_body)*57.2958;

            quat1 = squeeze(simOut.q1);
            quat2 = squeeze(simOut.q2);
            quat3 = squeeze(simOut.q3);
            quat4 = squeeze(simOut.q4);
            assignin('base','q1',quat1)
            assignin('base','q2',quat2)
            assignin('base','q3',quat3)
            assignin('base','q4',quat4)

            X_ECI = squeeze(simOut.X_ECI);
            Y_ECI = squeeze(simOut.Y_ECI);
            Z_ECI = squeeze(simOut.Z_ECI);

            s_x = squeeze(simOut.s_x);
            s_y = squeeze(simOut.s_y);
            s_z = squeeze(simOut.s_z);

            h1 = squeeze(simOut.h1_w)*1e03;
            h2 = squeeze(simOut.h2_w)*1e03;
            h3 = squeeze(simOut.h3_w)*1e03;

            m1 = squeeze(simOut.m_1);
            m2 = squeeze(simOut.m_2);
            m3 = squeeze(simOut.m_3);

            roll_err    = squeeze(simOut.roll_err);
            pitch_err   = squeeze(simOut.pitch_err);
            yaw_err     = squeeze(simOut.yaw_err);
            

            % Normalize shapes
            app.t     = tout(:);
            app.att   = [roll(:)  pitch(:)  yaw(:)];
            app.rates = [w1_body(:) w2_body(:) w3_body(:)];
            app.q     = [quat1(:) quat2(:) quat3(:) quat4(:)];
            app.rI    = [X_ECI(:) Y_ECI(:) Z_ECI(:)];
            app.sI    = [s_x(:) s_y(:) s_z(:)];
            app.h_w   = [h1(:) h2(:) h3(:)];
            app.magM  = [m1(:) m2(:) m3(:)];
            app.err   = [roll_err(:) pitch_err(:) yaw_err(:)];

            % Init plots + scrubber + timer
            app.initAnimation();

            close(waitbox)
            delete(stopapp)
            
            set(app.PlayButton, 'Enable', 'on')
            set(app.ScrubberSlider,'Enable','on')
            set(app.AnimationSpeedSlider,'Enable','on')
            set(app.GenerateOutputButton,'Enable','on')


        end

        % Button pushed function: LoadSimulinkModelButton
        function LoadSimulinkModelButtonPushed(app, event)
        waitbox2 = app.CustomWaitBox('Opening Simulink Model...');
        open(app.ModelName)
        close(waitbox2)
        set(app.RunSimulationButton,'Enable','on')
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)

        app.tPlay0 = tic;
        app.tDisp0 = app.t(max(1,app.k));   % resume from current index
        if strcmp(app.aniTmr.Running,'off'), start(app.aniTmr); end
        set(app.PauseButton, 'Enable', 'on')
        set(app.StopButton, 'Enable', 'on')
        set(app.PlayButton, 'Enable', 'off')

        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            if ~isempty(app.aniTmr) && strcmp(app.aniTmr.Running,'on'), stop(app.aniTmr); end
            set(app.PauseButton, 'Enable', 'off')
            set(app.PlayButton, 'Enable', 'on')
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if ~isempty(app.aniTmr), stop(app.aniTmr); end
            app.k = 1; app.ScrubberSlider.Value = app.t(1);
            app.renderFrame(app.k);
            set(app.PauseButton, 'Enable', 'off')
            set(app.StopButton, 'Enable', 'off')
            set(app.PlayButton, 'Enable', 'on')
        end

        % Value changing function: ScrubberSlider
        function ScrubberSliderValueChanging2(app, event)
            if isempty(app.t), return; end
            % jump to closest index for selected time
            tSel = event.Value;
            app.k = max(1, round(interp1(app.t, 1:app.N, tSel, 'nearest', 1)));
            app.renderFrame(app.k);
            
        end

        % Value changing function: AnimationSpeedSlider
        function AnimationSpeedSliderValueChanging(app, event)
            app.speedFactor = event.Value;   % 0.25 .. 4
            app.applySpeed(); 
            
        end

        % Value changed function: SimulationTimesecEditField
        function SimulationTimesecEditFieldValueChanged(app, event)
            tspan = app.SimulationTimesecEditField.Value;
            assignin('base', 'tspan', tspan)
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            mode = app.ListBox.Value;
            assignin('base', 'mode', mode)
        end

        % Value changed function: K_dEditField
        function ControllerGainK_dEditFieldValueChanged(app, event)
            Kd = app.K_dEditField.Value;
            assignin('base', 'K_d', Kd)
        end

        % Value changed function: K_pEditField
        function ControllerGainK_pEditFieldValueChanged(app, event)
            Kp = app.K_pEditField.Value;
            assignin('base', 'K_p', Kp)
        end

        % Value changed function: K_wEditField
        function ControllerGainK_wEditFieldValueChanged(app, event)
            Kw = app.K_wEditField.Value;
            assignin('base','K_w',Kw)
        end

        % Value changed function: wB_1
        function wB_1ValueChanged(app, event)
            wB1 = app.wB_1.Value;
            assignin('base','wB_1',wB1)
        end

        % Value changed function: wB_2
        function wB_2ValueChanged(app, event)
            wB2 = app.wB_2.Value;
            assignin('base','wB_2',wB2)
        end

        % Value changed function: wB_3
        function wB_3ValueChanged(app, event)
            wB3 = app.wB_3.Value;
            assignin('base','wB_3',wB3)
        end

        % Value changed function: wW_3
        function wW_3ValueChanged(app, event)
            wW3 = app.wW_3.Value;
            assignin("base","wW_3",wW3)
        end

        % Value changed function: AltitudekmEditField
        function AltitudekmEditFieldValueChanged(app, event)
            app.oe2rv();
        end

        % Value changed function: EccentricityEditField
        function EccentricityEditFieldValueChanged(app, event)
            app.oe2rv();
        end

        % Value changed function: idegEditField
        function idegEditFieldValueChanged(app, event)
            app.oe2rv();
        end

        % Value changed function: OmegadegEditField
        function OmegadegEditFieldValueChanged(app, event)
            app.oe2rv();
        end

        % Value changed function: omegadegEditField
        function omegadegEditFieldValueChanged(app, event)
            app.oe2rv();
        end

        % Value changed function: t_psecEditField
        function t_psecEditFieldValueChanged(app, event)
            app.oe2rv();
        end

        % Value changed function: GenerateattitudefileCheckBox
        function GenerateattitudefileCheckBoxValueChanged(app, event)
            write_output = app.GenerateattitudefileCheckBox.Value;
            assignin('base','write_output',write_output)
        end

        % Value changed function: PlotgraphsCheckBox
        function PlotgraphsCheckBoxValueChanged(app, event)
            plot_graph = app.PlotgraphsCheckBox.Value;
            assignin('base','plot_graph',plot_graph)
        end

        % Button pushed function: GenerateOutputButton
        function GenerateOutputButtonPushed(app, event)
            evalin('base', "run('generateOutput.m')");
        end

        % Value changed function: J_wheels
        function J_wheelsValueChanged(app, event)
            J_wheels0 = app.J_wheels.Value;
            assignin('base','J_wheels',J_wheels0)
        end

        % Value changed function: q1_ini
        function q1_iniValueChanged(app, event)
            q1_ini = app.q1_ini.Value;
            assignin('base','q1_ini',q1_ini)
        end

        % Value changed function: q2_ini
        function q2_iniValueChanged(app, event)
            q2_ini = app.q2_ini.Value;
            assignin('base',"q2_ini",q2_ini)
        end

        % Value changed function: q3_ini
        function q3_iniValueChanged(app, event)
            q3_ini = app.q3_ini.Value;
            assignin('base','q3_ini',q3_ini)
        end

        % Value changed function: q4_ini
        function q4_iniValueChanged(app, event)
            q4_ini = app.q4_ini.Value;
            assignin('base','q4_ini',q4_ini)
        end

        % Value changed function: LocationCEditField
        function LocationCEditFieldValueChanged(app, event)
            assignin('base','location',app.LocationCEditField.Value)
            
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            assignin('base','filename',[app.FileNameEditField.Value,'.a'])
            
        end

        % Callback function
        function UpdateOrbitButtonPushed(app, event)
            app.oe2rv();

        end

        % Value changed function: I_xyEditField
        function I_xyEditFieldValueChanged(app, event)
            app.makeJ();
        end

        % Value changed function: I_xzEditField
        function I_xzEditFieldValueChanged(app, event)
            app.makeJ();
        end

        % Value changed function: I_xxEditField
        function I_xxEditFieldValueChanged(app, event)
            app.makeJ();
        end

        % Value changed function: I_yxEditField
        function I_yxEditFieldValueChanged(app, event)
            app.makeJ();
        end

        % Value changed function: I_yyEditField
        function I_yyEditFieldValueChanged(app, event)
            app.makeJ();
        end

        % Value changed function: I_yzEditField
        function I_yzEditFieldValueChanged(app, event)
            app.makeJ();
        end

        % Value changed function: I_zxEditField
        function I_zxEditFieldValueChanged(app, event)
            app.makeJ();
        end

        % Value changed function: I_zyEditField
        function I_zyEditFieldValueChanged(app, event)
            app.makeJ();
        end

        % Value changed function: I_zzEditField
        function I_zzEditFieldValueChanged(app, event)
            app.makeJ();
        end

        % Value changed function: EnablefilteringCheckBox
        function EnablefilteringCheckBoxValueChanged(app, event)
            useFilter = app.EnablefilteringCheckBox.Value;
            assignin('base',"useFilter",useFilter)
        end

        % Value changed function: TruestatesCheckBox
        function TruestatesCheckBoxValueChanged(app, event)
            truevals = app.TruestatesCheckBox.Value;
            assignin('base',"truevals",truevals)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ADCSfigure = uifigure('Visible', 'off');
            app.ADCSfigure.Name = 'ADCS Simulator';
            app.GridLayout25 = uigridlayout(app.ADCSfigure);
            app.TabGroup3 = uitabgroup(app.GridLayout25);
            app.SetupTab = uitab(app.TabGroup3);
            app.SetupTab.Tooltip = {''};
            app.SetupTab.Title = 'Setup';
            app.GridLayout26 = uigridlayout(app.SetupTab);
            app.ModeSelectionPanel = uipanel(app.GridLayout26);
            app.ModeSelectionPanel.Title = 'Mode Selection';
            app.GridLayout28 = uigridlayout(app.ModeSelectionPanel);
            app.ListBox = uilistbox(app.GridLayout28);
            app.ListBox.Items = {'Mission mode (nadir)', 'Idle mode (sun tracking)', 'Operation mode (from table)', 'Comms mode (target tracking)', 'Detumble mode', 'Desaturation mode', 'Safe mode', 'No control', 'B-dot control'};
            app.ListBox.ItemsData = [0 1 2 3 4 5 6 7 8];
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 0;
            app.InitialConditionsPanel = uipanel(app.GridLayout26);
            app.InitialConditionsPanel.Title = 'Initial Conditions';
            app.GridLayout29 = uigridlayout(app.InitialConditionsPanel);
            app.SpacecraftBodyLabel = uilabel(app.GridLayout29);
            app.SpacecraftBodyLabel.Text = 'Spacecraft Body';
            app.mathbfomega_b_0mathrmdegsLabel = uilabel(app.GridLayout29);
            app.mathbfomega_b_0mathrmdegsLabel.Interpreter = 'latex';
            app.mathbfomega_b_0mathrmdegsLabel.Text = '$\mathbf \omega_{b_0}$ (deg/s)';
            app.wB_1 = uieditfield(app.GridLayout29, 'numeric');
            app.wB_1.ValueDisplayFormat = '%5.2f';
            app.wB_1.ValueChangedFcn = createCallbackFcn(app, @wB_1ValueChanged, true);
            app.wB_2 = uieditfield(app.GridLayout29, 'numeric');
            app.wB_2.ValueDisplayFormat = '%5.2f';
            app.wB_2.ValueChangedFcn = createCallbackFcn(app, @wB_2ValueChanged, true);
            app.wB_2.Value = -0.0649026;
            app.wB_3 = uieditfield(app.GridLayout29, 'numeric');
            app.wB_3.ValueDisplayFormat = '%5.2f';
            app.wB_3.ValueChangedFcn = createCallbackFcn(app, @wB_3ValueChanged, true);
            app.mathbfq_0_1Label = uilabel(app.GridLayout29);
            app.mathbfq_0_1Label.Interpreter = 'latex';
            app.mathbfq_0_1Label.Text = '$q_{0_1}$';
            app.q1_ini = uieditfield(app.GridLayout29, 'numeric');
            app.q1_ini.ValueDisplayFormat = '%5.4f';
            app.q1_ini.ValueChangedFcn = createCallbackFcn(app, @q1_iniValueChanged, true);
            app.q1_ini.Value = 0.386286;
            app.q2_ini = uieditfield(app.GridLayout29, 'numeric');
            app.q2_ini.ValueDisplayFormat = '%5.4f';
            app.q2_ini.ValueChangedFcn = createCallbackFcn(app, @q2_iniValueChanged, true);
            app.q2_ini.Value = -0.59172;
            app.q3_ini = uieditfield(app.GridLayout29, 'numeric');
            app.q3_ini.ValueDisplayFormat = '%5.4f';
            app.q3_ini.ValueChangedFcn = createCallbackFcn(app, @q3_iniValueChanged, true);
            app.q3_ini.Value = 0.309974;
            app.q4_ini = uieditfield(app.GridLayout29, 'numeric');
            app.q4_ini.ValueDisplayFormat = '%5.4f';
            app.q4_ini.ValueChangedFcn = createCallbackFcn(app, @q4_iniValueChanged, true);
            app.q4_ini.Value = 0.636056;
            app.ReactionWheelsLabel = uilabel(app.GridLayout29);
            app.ReactionWheelsLabel.Text = 'Reaction Wheels';
            app.wW_2 = uieditfield(app.GridLayout29, 'numeric');
            app.wW_3 = uieditfield(app.GridLayout29, 'numeric');
            app.wW_3.ValueChangedFcn = createCallbackFcn(app, @wW_3ValueChanged, true);
            app.mathbfomega_W_0radsLabel = uilabel(app.GridLayout29);
            app.mathbfomega_W_0radsLabel.Interpreter = 'latex';
            app.mathbfomega_W_0radsLabel.Text = '$\mathbf \omega_{W_0}$ (rad/s)';
            app.wW_1 = uieditfield(app.GridLayout29, 'numeric');
            app.q_0_2Label = uilabel(app.GridLayout29);
            app.q_0_2Label.Interpreter = 'latex';
            app.q_0_2Label.Text = '$q_{0_2}$';
            app.q_0_3Label = uilabel(app.GridLayout29);
            app.q_0_3Label.Interpreter = 'latex';
            app.q_0_3Label.Text = '$q_{0_3}$';
            app.q_0_4Label = uilabel(app.GridLayout29);
            app.q_0_4Label.Interpreter = 'latex';
            app.q_0_4Label.Text = '$q_{0_4}$';
            app.OrbitElementsPanel = uipanel(app.GridLayout26);
            app.OrbitElementsPanel.Title = 'Orbit Elements';
            app.GridLayout30 = uigridlayout(app.OrbitElementsPanel);
            app.AltitudekmEditFieldLabel = uilabel(app.GridLayout30);
            app.AltitudekmEditFieldLabel.Interpreter = 'latex';
            app.AltitudekmEditFieldLabel.Text = 'Altitude (km)';
            app.AltitudekmEditField = uieditfield(app.GridLayout30, 'numeric');
            app.AltitudekmEditField.ValueChangedFcn = createCallbackFcn(app, @AltitudekmEditFieldValueChanged, true);
            app.AltitudekmEditField.Value = 400;
            app.EccentricityLabel = uilabel(app.GridLayout30);
            app.EccentricityLabel.Interpreter = 'latex';
            app.EccentricityLabel.Text = 'Eccentricity';
            app.EccentricityEditField = uieditfield(app.GridLayout30, 'numeric');
            app.EccentricityEditField.ValueDisplayFormat = '%5.2f';
            app.EccentricityEditField.ValueChangedFcn = createCallbackFcn(app, @EccentricityEditFieldValueChanged, true);
            app.idegLabel = uilabel(app.GridLayout30);
            app.idegLabel.Interpreter = 'latex';
            app.idegLabel.Text = '$i$ (deg)';
            app.idegEditField = uieditfield(app.GridLayout30, 'numeric');
            app.idegEditField.ValueChangedFcn = createCallbackFcn(app, @idegEditFieldValueChanged, true);
            app.idegEditField.Value = 97.1555;
            app.OmegadegEditFieldLabel = uilabel(app.GridLayout30);
            app.OmegadegEditFieldLabel.Interpreter = 'latex';
            app.OmegadegEditFieldLabel.Text = '$\Omega$ (deg)';
            app.OmegadegEditField = uieditfield(app.GridLayout30, 'numeric');
            app.OmegadegEditField.ValueChangedFcn = createCallbackFcn(app, @OmegadegEditFieldValueChanged, true);
            app.OmegadegEditField.Value = 59.1096;
            app.omegadegEditFieldLabel = uilabel(app.GridLayout30);
            app.omegadegEditFieldLabel.Interpreter = 'latex';
            app.omegadegEditFieldLabel.Text = '$\omega$ (deg)';
            app.omegadegEditField = uieditfield(app.GridLayout30, 'numeric');
            app.omegadegEditField.ValueDisplayFormat = '%5.2f';
            app.omegadegEditField.ValueChangedFcn = createCallbackFcn(app, @omegadegEditFieldValueChanged, true);
            app.t_psecLabel = uilabel(app.GridLayout30);
            app.t_psecLabel.Interpreter = 'latex';
            app.t_psecLabel.Text = '$t_p$ (sec)';
            app.t_psecEditField = uieditfield(app.GridLayout30, 'numeric');
            app.t_psecEditField.ValueDisplayFormat = '%5.2f';
            app.t_psecEditField.ValueChangedFcn = createCallbackFcn(app, @t_psecEditFieldValueChanged, true);
            app.t_psecEditField.Value = 5552.46;
            app.MassPropertiesPanel = uipanel(app.GridLayout26);
            app.MassPropertiesPanel.Title = 'Mass Properties';
            app.GridLayout32 = uigridlayout(app.MassPropertiesPanel);
            app.SpacecraftInertiaMatrixLabel = uilabel(app.GridLayout32);
            app.SpacecraftInertiaMatrixLabel.Text = 'Spacecraft Inertia Matrix';
            app.I_ximathrmkgm2Label = uilabel(app.GridLayout32);
            app.I_ximathrmkgm2Label.Interpreter = 'latex';
            app.I_ximathrmkgm2Label.Text = '$I_{xi} ~ \mathrm{(kg.m^2)}$ ';
            app.I_yimathrmkgm2Label = uilabel(app.GridLayout32);
            app.I_yimathrmkgm2Label.Interpreter = 'latex';
            app.I_yimathrmkgm2Label.Text = '$I_{yi}~ \mathrm{(kg.m^2)}$ ';
            app.I_zimathrmkgm2Label = uilabel(app.GridLayout32);
            app.I_zimathrmkgm2Label.Interpreter = 'latex';
            app.I_zimathrmkgm2Label.Text = '$I_{zi} ~ \mathrm{(kg.m^2)}$ ';
            app.WheelsMomentofInertiaLabel = uilabel(app.GridLayout32);
            app.WheelsMomentofInertiaLabel.Text = 'Wheels Moment of Inertia';
            app.I_zmathrmkgm2Label = uilabel(app.GridLayout32);
            app.I_zmathrmkgm2Label.Interpreter = 'latex';
            app.I_zmathrmkgm2Label.Text = '$I_z ~ \mathrm{(kg.m^2)}$ ';
            app.J_wheels = uieditfield(app.GridLayout32, 'numeric');
            app.J_wheels.ValueChangedFcn = createCallbackFcn(app, @J_wheelsValueChanged, true);
            app.J_wheels.Value = 1.8389e-05;
            app.I_zzEditField = uieditfield(app.GridLayout32, 'numeric');
            app.I_zzEditField.ValueDisplayFormat = '%.4f';
            app.I_zzEditField.ValueChangedFcn = createCallbackFcn(app, @I_zzEditFieldValueChanged, true);
            app.I_zzEditField.Value = 0.0067;
            app.I_xxEditField = uieditfield(app.GridLayout32, 'numeric');
            app.I_xxEditField.ValueDisplayFormat = '%.4f';
            app.I_xxEditField.ValueChangedFcn = createCallbackFcn(app, @I_xxEditFieldValueChanged, true);
            app.I_xxEditField.Value = 0.0333;
            app.I_yyEditField = uieditfield(app.GridLayout32, 'numeric');
            app.I_yyEditField.ValueDisplayFormat = '%.4f';
            app.I_yyEditField.ValueChangedFcn = createCallbackFcn(app, @I_yyEditFieldValueChanged, true);
            app.I_yyEditField.Value = 0.0333;
            app.I_xyEditField = uieditfield(app.GridLayout32, 'numeric');
            app.I_xyEditField.ValueDisplayFormat = '%.4f';
            app.I_xyEditField.ValueChangedFcn = createCallbackFcn(app, @I_xyEditFieldValueChanged, true);
            app.I_xzEditField = uieditfield(app.GridLayout32, 'numeric');
            app.I_xzEditField.ValueDisplayFormat = '%.4f';
            app.I_xzEditField.ValueChangedFcn = createCallbackFcn(app, @I_xzEditFieldValueChanged, true);
            app.I_yxEditField = uieditfield(app.GridLayout32, 'numeric');
            app.I_yxEditField.ValueDisplayFormat = '%.4f';
            app.I_yxEditField.ValueChangedFcn = createCallbackFcn(app, @I_yxEditFieldValueChanged, true);
            app.I_yzEditField = uieditfield(app.GridLayout32, 'numeric');
            app.I_yzEditField.ValueDisplayFormat = '%.4f';
            app.I_yzEditField.ValueChangedFcn = createCallbackFcn(app, @I_yzEditFieldValueChanged, true);
            app.I_zyEditField = uieditfield(app.GridLayout32, 'numeric');
            app.I_zyEditField.ValueDisplayFormat = '%.4f';
            app.I_zyEditField.ValueChangedFcn = createCallbackFcn(app, @I_zyEditFieldValueChanged, true);
            app.I_zxEditField = uieditfield(app.GridLayout32, 'numeric');
            app.I_zxEditField.ValueDisplayFormat = '%.4f';
            app.I_zxEditField.ValueChangedFcn = createCallbackFcn(app, @I_zxEditFieldValueChanged, true);
            app.I_ixmathrmkgm2Label = uilabel(app.GridLayout32);
            app.I_ixmathrmkgm2Label.Interpreter = 'latex';
            app.I_ixmathrmkgm2Label.Text = '$I_{ix} ~ \mathrm{(kg.m^2)}$ ';
            app.I_iymathrmkgm2Label = uilabel(app.GridLayout32);
            app.I_iymathrmkgm2Label.Interpreter = 'latex';
            app.I_iymathrmkgm2Label.Text = '$I_{iy} ~ \mathrm{(kg.m^2)}$ ';
            app.I_izmathrmkgm2Label = uilabel(app.GridLayout32);
            app.I_izmathrmkgm2Label.Interpreter = 'latex';
            app.I_izmathrmkgm2Label.Text = '$I_{iz} ~ \mathrm{(kg.m^2)}$ ';
            app.SimulationSetupandRunPanel = uipanel(app.GridLayout26);
            app.SimulationSetupandRunPanel.Title = 'Simulation Setup and Run';
            app.GridLayout33 = uigridlayout(app.SimulationSetupandRunPanel);
            app.LoadSimulinkModelButton = uibutton(app.GridLayout33, 'push');
            app.LoadSimulinkModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSimulinkModelButtonPushed, true);
            app.LoadSimulinkModelButton.Text = {'Load Simulink '; 'Model'};
            app.RunSimulationButton = uibutton(app.GridLayout33, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.EnableexternaltorquesCheckBox = uicheckbox(app.GridLayout33);
            app.EnableexternaltorquesCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableexternaltorquesCheckBoxValueChanged, true);
            app.EnableexternaltorquesCheckBox.Text = 'Enable external torques';
            app.EnableexternaltorquesCheckBox.Value = true;
            app.EnablefilteringCheckBox = uicheckbox(app.GridLayout33);
            app.EnablefilteringCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnablefilteringCheckBoxValueChanged, true);
            app.EnablefilteringCheckBox.Text = 'Enable filtering';
            app.EnablefilteringCheckBox.Value = true;
            app.TruestatesCheckBox = uicheckbox(app.GridLayout33);
            app.TruestatesCheckBox.ValueChangedFcn = createCallbackFcn(app, @TruestatesCheckBoxValueChanged, true);
            app.TruestatesCheckBox.Text = 'True states';
            app.SimulationTimesecEditFieldLabel = uilabel(app.GridLayout33);
            app.SimulationTimesecEditFieldLabel.Text = 'Simulation Time (sec)';
            app.SimulationTimesecEditField = uieditfield(app.GridLayout33, 'numeric');
            app.SimulationTimesecEditField.ValueDisplayFormat = '%.1f';
            app.SimulationTimesecEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationTimesecEditFieldValueChanged, true);
            app.SimulationTimesecEditField.Value = 14400;
            app.ControllerGainsPanel = uipanel(app.GridLayout26);
            app.ControllerGainsPanel.Title = 'Controller Gains';
            app.GridLayout31 = uigridlayout(app.ControllerGainsPanel);
            app.ModifiedPDControllerLabel = uilabel(app.GridLayout31);
            app.ModifiedPDControllerLabel.Text = 'Modified PD Controller';
            app.K_dEditFieldLabel = uilabel(app.GridLayout31);
            app.K_dEditFieldLabel.Interpreter = 'latex';
            app.K_dEditFieldLabel.Text = '$K_d$';
            app.K_dEditField = uieditfield(app.GridLayout31, 'numeric');
            app.K_dEditField.ValueChangedFcn = createCallbackFcn(app, @ControllerGainK_dEditFieldValueChanged, true);
            app.K_dEditField.Value = 0.0075;
            app.K_pEditFieldLabel = uilabel(app.GridLayout31);
            app.K_pEditFieldLabel.Interpreter = 'latex';
            app.K_pEditFieldLabel.Text = '$K_p$';
            app.K_pEditField = uieditfield(app.GridLayout31, 'numeric');
            app.K_pEditField.ValueChangedFcn = createCallbackFcn(app, @ControllerGainK_pEditFieldValueChanged, true);
            app.K_pEditField.Value = 0.000125;
            app.BdotControllerLabel = uilabel(app.GridLayout31);
            app.BdotControllerLabel.Text = 'B-dot Controller';
            app.K_wEditFieldLabel = uilabel(app.GridLayout31);
            app.K_wEditFieldLabel.Interpreter = 'latex';
            app.K_wEditFieldLabel.Text = '$K_w$';
            app.K_wEditField = uieditfield(app.GridLayout31, 'numeric');
            app.K_wEditField.ValueChangedFcn = createCallbackFcn(app, @ControllerGainK_wEditFieldValueChanged, true);
            app.K_wEditField.Value = 500000;
            app.Panel_3 = uipanel(app.GridLayout26);
            app.GridLayout35 = uigridlayout(app.Panel_3);
            app.Image = uiimage(app.GridLayout35);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Misc', 'Straight Logo White.png');
            app.Ver2Release3Label = uilabel(app.GridLayout35);
            app.Ver2Release3Label.Text = 'Ver 2 Release 3     ';
            app.AnimationTab = uitab(app.TabGroup3);
            app.AnimationTab.Tooltip = {''};
            app.AnimationTab.Title = 'Animation';
            app.GridLayout24 = uigridlayout(app.AnimationTab);
            app.Panel = uipanel(app.GridLayout24);
            app.GridLayout19 = uigridlayout(app.Panel);
            app.OrbitAxes = uiaxes(app.GridLayout19);
            title(app.OrbitAxes, 'Orbit Viewer')
            xlabel(app.OrbitAxes, 'X (km)')
            ylabel(app.OrbitAxes, 'Y (km)')
            zlabel(app.OrbitAxes, 'Z (km)')
            app.RatesAxes = uiaxes(app.GridLayout19);
            title(app.RatesAxes, 'Spacecraft Body Rates')
            xlabel(app.RatesAxes, 'Time (s)')
            ylabel(app.RatesAxes, '\omega_B (deg/s)')
            zlabel(app.RatesAxes, 'Z')
            app.AttAxes = uiaxes(app.GridLayout19);
            title(app.AttAxes, 'Spacecraft Attitude')
            xlabel(app.AttAxes, 'Time (s)')
            ylabel(app.AttAxes, 'Euler Angles (deg)')
            zlabel(app.AttAxes, 'Z')
            app.MagMomentAxes = uiaxes(app.GridLayout19);
            title(app.MagMomentAxes, 'Magnetic Moment')
            xlabel(app.MagMomentAxes, 'Time (s)')
            ylabel(app.MagMomentAxes, 'm (Am^2)')
            zlabel(app.MagMomentAxes, 'Z')
            app.PointingErrAxes = uiaxes(app.GridLayout19);
            title(app.PointingErrAxes, 'Pointing Error')
            xlabel(app.PointingErrAxes, 'Time (s)')
            ylabel(app.PointingErrAxes, 'Euler Angles (deg)')
            zlabel(app.PointingErrAxes, 'Z')
            app.MomentumAxes = uiaxes(app.GridLayout19);
            title(app.MomentumAxes, 'Stored Angular Momentum')
            xlabel(app.MomentumAxes, 'Time (s)')
            ylabel(app.MomentumAxes, 'h (mNms)')
            zlabel(app.MomentumAxes, 'Z')
            app.AnimationSpeedSlider = uislider(app.GridLayout19);
            app.AnimationSpeedSlider.Limits = [100 500];
            app.AnimationSpeedSlider.MajorTicks = [100 500];
            app.AnimationSpeedSlider.MajorTickLabels = {'Slow', 'Fast'};
            app.AnimationSpeedSlider.ValueChangingFcn = createCallbackFcn(app, @AnimationSpeedSliderValueChanging, true);
            app.AnimationSpeedSlider.Value = 100;
            app.AnimationTimeLabel = uilabel(app.GridLayout19);
            app.AnimationTimeLabel.Text = 'Animation Time';
            app.Panel_2 = uipanel(app.GridLayout19);
            app.GridLayout27 = uigridlayout(app.Panel_2);
            app.PlayButton = uibutton(app.GridLayout27, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.GridLayout27, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.GridLayout27, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.AnimationControlLabel = uilabel(app.GridLayout27);
            app.AnimationControlLabel.Text = {'Animation'; 'Control'};
            app.ScrubberSlider = uislider(app.GridLayout19);
            app.ScrubberSlider.MajorTicks = [];
            app.ScrubberSlider.MajorTickLabels = {''};
            app.ScrubberSlider.ValueChangingFcn = createCallbackFcn(app, @ScrubberSliderValueChanging2, true);
            app.OutputTab = uitab(app.TabGroup3);
            app.OutputTab.Tooltip = {''};
            app.OutputTab.Title = 'Output';
            app.GridLayout34 = uigridlayout(app.OutputTab);
            app.Panel_4 = uipanel(app.GridLayout34);
            app.GridLayout36 = uigridlayout(app.Panel_4);
            app.GenerateattitudefileCheckBox = uicheckbox(app.GridLayout36);
            app.GenerateattitudefileCheckBox.ValueChangedFcn = createCallbackFcn(app, @GenerateattitudefileCheckBoxValueChanged, true);
            app.GenerateattitudefileCheckBox.Text = 'Generate attitude file';
            app.PlotgraphsCheckBox = uicheckbox(app.GridLayout36);
            app.PlotgraphsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotgraphsCheckBoxValueChanged, true);
            app.PlotgraphsCheckBox.Text = 'Plot graphs';
            app.PlotgraphsCheckBox.Value = true;
            app.LocationCEditFieldLabel = uilabel(app.GridLayout36);
            app.LocationCEditFieldLabel.Text = 'Location (C:/)';
            app.LocationCEditField = uieditfield(app.GridLayout36, 'text');
            app.LocationCEditField.ValueChangedFcn = createCallbackFcn(app, @LocationCEditFieldValueChanged, true);
            app.LocationCEditField.Value = 'C:\Users\nimis\Documents\MATLAB\CUonOrbit\ADCS_sim\Attitude Files\';
            app.FileNameEditFieldLabel = uilabel(app.GridLayout36);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.GridLayout36, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.FileNameEditField.Value = 'Att_Data';
            app.GenerateOutputButton = uibutton(app.GridLayout36, 'push');
            app.GenerateOutputButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateOutputButtonPushed, true);
            app.GenerateOutputButton.Text = 'Generate Output';
            app.NimishDhawanLabel = uilabel(app.GridLayout36);
            app.NimishDhawanLabel.Text = '© 2025 Nimish Dhawan';
            % Show the figure after all components are created
            app.ADCSfigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ADCSgui_v2_dark

            % Associate the Simulink Model
            app.Simulation = simulation('ADCS_onOrbit');

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ADCSfigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ADCSfigure)
        end
    end
end